classdef CameraSync < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        DefaultScaleLabel           matlab.ui.control.Label
        ScanimageButton             matlab.ui.control.StateButton
        OperationTimeListLabel      matlab.ui.control.Label
        ExportButton                matlab.ui.control.Button
        TimeList                    matlab.ui.control.ListBox
        CallSignalAcquisition       matlab.ui.control.Button
        StartButton                 matlab.ui.control.Button
        StopButton                  matlab.ui.control.Button
        AdvancedButton              matlab.ui.control.StateButton
        ROIRegionPanel              matlab.ui.container.Panel
        XOffsetSpinner              matlab.ui.control.Spinner
        XOffsetSpinnerLabel         matlab.ui.control.Label
        HeightSpinner               matlab.ui.control.Spinner
        HeightSpinnerLabel          matlab.ui.control.Label
        YOffsetSpinner              matlab.ui.control.Spinner
        YOffsetSpinnerLabel         matlab.ui.control.Label
        WidthSpinner                matlab.ui.control.Spinner
        WidthSpinnerLabel           matlab.ui.control.Label
        VideoFileIndexSpinner       matlab.ui.control.Spinner
        VideoFileIndexSpinnerLabel  matlab.ui.control.Label
        WaitingforLabel             matlab.ui.control.Label
        Lamp                        matlab.ui.control.Lamp
        CameraPreviewButton         matlab.ui.control.Button
        CameraConnectButton         matlab.ui.control.Button
        FolderSelectButton          matlab.ui.control.Button
        FilePathEditField           matlab.ui.control.EditField
        FilePathEditFieldLabel      matlab.ui.control.Label
    end

    
    properties (Access = private)
        DialogApp; % Dialog box app
        acqAbortListener;
        acqDoneListener;
        stopTriggerPressed = 0;
        timetableExported = 0;
        timeTable; % 存储所有操作的时间和衍生文件名
        timer; % 存储多对tic toc
    end
    
    properties (Access = public)
        cameraObj; % 相机
        hSI = scanimage.SI.empty; % Scanimage 工作区变量
    end
    
    methods (Access = private)

        function disableUI(app)
            app.FilePathEditField.Editable = "off";
            app.CameraPreviewButton.Enable = "off";
            app.FolderSelectButton.Enable = "off";
            app.VideoFileIndexSpinner.Enable="off";
            app.VideoFileIndexSpinner.Editable ="off";
            app.ScanimageButton.Enable="off";
            app.StartButton.Enable = "off";
        end

        
        function enableUI(app)
            app.CameraPreviewButton.Enable = 'on';
            app.FolderSelectButton.Enable = "on";
            app.FilePathEditField.Enable = "on";
            app.VideoFileIndexSpinner.Enable="on";
            app.VideoFileIndexSpinner.Editable ="on";
            app.ScanimageButton.Enable="on";
            app.AdvancedButton.Enable = 'on';
            app.CameraConnectButton.Enable = "on";

        end

        function acqDoneCallback(app,~,~)
            %% 同步关键步骤3：scanimage正常采集结束
            % for scanimage acqDone callback
            if app.hSI.acqState == "grab" && app.hSI.extTrigEnable
                % grab正常结束触发，focus结束不触发 && 只有开启external trigger才触发 &&
                % 没有主动点击stop acq才触发
                app.syncStop();
                % ui update
                app.Lamp.Color = [0.00,1.00,0.00];
                app.WaitingforLabel.Text = "Ready For Grabing";
                app.StartButton.Enable = "on";
                app.StopButton.Enable = "off";
            end

        end

        function acqAbortCallback(app,~,~)
            %             t = datetime('now','Format','yyyy-MM-dd HH:mm:ss.SSS');
            %             cprintf('-comment',"AcqAbort Trigger at: %s\n",t);
            % for scanimage acqAbort callback
            if app.hSI.acqState == "grab" && app.hSI.extTrigEnable
                % 避免grab 正常结束也触发（grab正常结束是idle，只有中途abort才是grab）  &&
                % 只有开启external trigger才触发 && 没有主动点击stop acq才触发
                app.camStop();
                % ui update
                app.Lamp.Color = [0.00,1.00,0.00];
                app.WaitingforLabel.Text = "Ready For Grabing";
                app.StartButton.Enable = "on";
                app.StopButton.Enable = "off";
            end

        end
                
        function syncStop(app)
            t = toc(app.timer.start);
            framesAq = app.cameraObj.FramesAcquired;
            stop(app.cameraObj);
            % 记录Marker
            if isequal(app.CallSignalAcquisition.Enable,"off")
                app.DialogApp.stopMarker();
            end
            close(app.cameraObj.DiskLogger);

            %% update

            workingText = '同步采集停止';
            % 统计操作次数
            app.timeTable.stopTimes = app.timeTable.stopTimes+1;
            operates = app.timeTable.grabTimes+app.timeTable.stopTimes;
            % 获取瞳孔数据名称
            moviename = sprintf("file_%05d.mp4",app.VideoFileIndexSpinner.Value);

            % 更新到统计表格上
            app.timeTable.list{operates,1} = workingText;
            app.timeTable.list{operates,2} = moviename;
            app.timeTable.list{operates,3} = t;
            app.timeTable.list{operates,4} = framesAq;

            % 实时更新到TimeList上
            str = [workingText, sprintf('%d',app.timeTable.stopTimes)];
            app.TimeList.Items{operates} = sprintf('%s: %f',str,t);

            app.VideoFileIndexSpinner.Value = app.VideoFileIndexSpinner.Value+1;
            if app.ScanimageButton.Value
                app.hSI.hScan2D.logFileCounter = app.VideoFileIndexSpinner.Value;
            end


            %% 组件设置调整
            app.Lamp.Color = [0.00,1.00,0.00];
            app.WaitingforLabel.Text = "Ready For Grabing";
            app.StartButton.Enable = "on";
            app.StopButton.Enable = "off";
        end
        
        function camStop(app)
            framesAq = app.cameraObj.FramesAcquired;
            t = toc(app.timer.start);
            stop(app.cameraObj);
            close(app.cameraObj.DiskLogger);

            %% update
            % 统计操作次数
            app.timeTable.stopTimes = app.timeTable.stopTimes+1;
            operates = app.timeTable.grabTimes+app.timeTable.stopTimes;
            % 获取瞳孔数据名称
            moviename = sprintf("file_%05d.mp4",app.VideoFileIndexSpinner.Value);

            % 更新到统计表格上
            workingText = '采集中途终止';

            app.timeTable.list{operates,1} = workingText;
            app.timeTable.list{operates,2} = moviename;
            app.timeTable.list{operates,3} = t;
            app.timeTable.list{operates,4} = framesAq;

            % 实时更新到TimeList上
            str = [workingText, sprintf('%d',app.timeTable.stopTimes)];
            app.TimeList.Items{operates} = sprintf('%s: %f',str,t);

            % 更新文本计数
            app.VideoFileIndexSpinner.Value = app.VideoFileIndexSpinner.Value+1;
            if app.ScanimageButton.Value
                app.hSI.hScan2D.logFileCounter = app.VideoFileIndexSpinner.Value;
            end

        end

    end
    
    methods (Access = public)
        
        function camInitial(app)
            videoName_part = sprintf("file_%05d.mp4",app.VideoFileIndexSpinner.Value);
            fullFileName = fullfile(app.FilePathEditField.Value,videoName_part);

            while exist(fullFileName,"file")
                app.VideoFileIndexSpinner.Value = app.VideoFileIndexSpinner.Value+1;
                videoName_part = sprintf("file_%05d.mp4",app.VideoFileIndexSpinner.Value);
                fullFileName = fullfile(app.FilePathEditField.Value,videoName_part);
            end

            if app.ScanimageButton.Value
                app.hSI.hScan2D.logFileCounter = app.VideoFileIndexSpinner.Value;
            end
            videoWriterObj = VideoWriter(fullFileName,'MPEG-4');
            app.cameraObj.DiskLogger = videoWriterObj;

            % 开启快门
            start(app.cameraObj);
        end

        function startGrab(app)
            
            app.timer.start = tic;
            t = 0;
            framesAq = app.cameraObj.FramesAcquired;
            %% 脑电同步关键步骤：见signal acquisition
            if isequal(app.CallSignalAcquisition.Enable,"off")
                app.DialogApp.startMarker();
            end

            app.timeTable.grabTimes = app.timeTable.grabTimes+1;
            operates = app.timeTable.grabTimes+app.timeTable.stopTimes;

            moviename = sprintf("file_%05d.mp4",app.VideoFileIndexSpinner.Value);

            workingText = '采集开始';

            % 更新到统计表格上
            app.timeTable.list{operates,1} = workingText;
            app.timeTable.list{operates,2} = moviename;
            app.timeTable.list{operates,3} = t;
            app.timeTable.list{operates,4} = framesAq;

            % 实时更新到TimeList上
            str = [workingText, sprintf('%d',app.timeTable.grabTimes)];
            app.TimeList.Items{operates} = sprintf('%s: %f',str,t);
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            currentPosition=app.UIFigure.Position;
            app.UIFigure.Position = [currentPosition(1), currentPosition(2), 435,currentPosition(4)];
            app.disableUI();
            %% 初始化部分统计参数
            app.timeTable.grabTimes = 0;
            app.timeTable.stopTimes = 0;
        end

        % Value changed function: ScanimageButton
        function ScanimageButtonValueChanged(app, event)
            value = app.ScanimageButton.Value;
            %% 同步关键步骤1：连接scanimage工作区
            if value
                % 获取scanimage hSI变量
                try
                    app.hSI = evalin('base', 'hSI');
                    app.VideoFileIndexSpinner.Value = app.hSI.hScan2D.logFileCounter;
                    app.acqDoneListener = addlistener(app.hSI.hUserFunctions,'acqDone', @app.acqDoneCallback);
                    app.acqAbortListener = addlistener(app.hSI.hUserFunctions, 'acqAbort', @app.acqAbortCallback); 
                    app.FilePathEditField.Value = app.hSI.hScan2D.logFilePath; 
                catch ME
                    uialert(app.UIFigure,ME.message ...
                    ,'Warning','Icon','warning');
                    return;
                end
            else
                delete(app.acqDoneListener);
                delete(app.acqAbortListener);
                app.hSI = scanimage.SI.empty;
            end
        end

        % Button pushed function: CameraConnectButton
        function CameraConnectButtonPushed(app, event)
            try
                % process bar
                progressDlg = uiprogressdlg(app.UIFigure,'Title','Connecting',...
        'Indeterminate','on');

                % connect to camera
                app.cameraObj = videoinput("winvideo","1","MJPG_1280x960", ...
                "LoggingMode","disk", ...
                "ReturnedColorSpace","grayscale", ...
                "ROIPosition",[280 0 720 720]);
                %                 "ROIPosition",[280 0 720 720], ...
                app.cameraObj.FramesPerTrigger = Inf;
                triggerconfig(app.cameraObj,"manual");

            catch ME
                uialert(app.UIFigure,ME.message ...
                    ,'Warning','Icon','warning');
                
                % close the dialog box
                close(progressDlg);
                return;
            end
            
            % close the dialog box
            close(progressDlg);

            % Enable CameraPreviewButton
            app.CameraPreviewButton.Enable = 'on';
            app.Lamp.Color = [0.00,1.00,0.00];
            app.WaitingforLabel.Text = "Ready for Grabing";
            app.enableUI();
            app.StartButton.Enable = "on";
            app.StopButton.Enable = "off";
        end

        % Button pushed function: FolderSelectButton
        function FolderSelectButtonPushed(app, event)
            % 选择文件夹路径
            dir_pre = app.FilePathEditField.Value;
            folder = uigetdir(dir_pre,"选择存储数据的文件夹");
            if folder == 0
                uialert(app.UIFigure,'未选择文件夹' ...
                    ,'Warning','Icon','warning');
                return;
            end

            videoName_part = sprintf("file_%05d.mp4",app.VideoFileIndexSpinner.Value);
            fullFileName = fullfile(folder,videoName_part);

            % 寻找不存在的最大编号文件编号数
            while exist(fullFileName,"file")
                app.VideoFileIndexSpinner.Value = app.VideoFileIndexSpinner.Value+1;
                videoName_part = sprintf("file_%05d.mp4",app.VideoFileIndexSpinner.Value);
                fullFileName = fullfile(folder,videoName_part);
                
            end

            app.FilePathEditField.Value = folder;

            if app.ScanimageButton.Value
                app.hSI.hScan2D.logFilePath = folder;
                app.hSI.hScan2D.logFileCounter = app.VideoFileIndexSpinner.Value;
            end
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            if ~isrunning(app.cameraObj)
                camInitial(app);
            end
            waitfor(app.cameraObj,"Running","on");
            %% 同步关键步骤2：相机连接、scanimage连接并处于grab状态，执行trigIssueSoftwareAcq()触发成像，startGrab(app)同步开启相机，并更新操作时间
            if app.hSI.acqState == "grab"
                app.hSI.hScan2D.trigIssueSoftwareAcq();
                trigger(app.cameraObj);
            else
                uialert(app.UIFigure,'Scanimage 请打开Ext Triggering，并点击Grab，Ready for Trigger' ...
                    ,'Warning','Icon','warning');
                return;
            end
            % 更新操作时间
            startGrab(app);

            % ui update
            app.Lamp.Color = [1.00,0.00,0.00];
            app.StartButton.Enable = 'off';
            app.StopButton.Enable = 'on';
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            if app.ScanimageButton.Value && app.hSI.acqState == "grab"
                uialert(app.UIFigure,'点击Scanimage的Abort停止录制' ...
                    ,'Warning','Icon','warning');
                return;
            else
            % 中途停止录制
                camStop(app);
            end

            % ui update
            app.Lamp.Color = [0.00,1.00,0.00];
            app.WaitingforLabel.Text = "Ready For Grabing";
            app.StartButton.Enable = "on";
            app.StopButton.Enable = "off";
        end

        % Button pushed function: CameraPreviewButton
        function CameraPreviewButtonPushed(app, event)
            preview(app.cameraObj);
        end

        % Value changed function: XOffsetSpinner
        function XOffsetSpinnerValueChanged(app, event)
            set(app.cameraObj, 'ROIPosition', [app.XOffsetSpinner.Value, app.YOffsetSpinner.Value, app.WidthSpinner.Value, app.HeightSpinner.Value]);
        end

        % Value changed function: YOffsetSpinner
        function YOffsetSpinnerValueChanged(app, event)
            set(app.cameraObj, 'ROIPosition', [app.XOffsetSpinner.Value, app.YOffsetSpinner.Value, app.WidthSpinner.Value, app.HeightSpinner.Value]);
        end

        % Value changed function: WidthSpinner
        function WidthSpinnerValueChanged(app, event)
            set(app.cameraObj, 'ROIPosition', [app.XOffsetSpinner.Value, app.YOffsetSpinner.Value, app.WidthSpinner.Value, app.HeightSpinner.Value]);
        end

        % Value changed function: HeightSpinner
        function HeightSpinnerValueChanged(app, event)
            set(app.cameraObj, 'ROIPosition', [app.XOffsetSpinner.Value, app.YOffsetSpinner.Value, app.WidthSpinner.Value, app.HeightSpinner.Value]);
        end

        % Value changed function: AdvancedButton
        function AdvancedButtonValueChanged(app, event)
            value = app.AdvancedButton.Value;
            currentPosition=app.UIFigure.Position;
            if value
                app.UIFigure.Position = [currentPosition(1), currentPosition(2), 870,currentPosition(4)];
            else
                app.UIFigure.Position = [currentPosition(1), currentPosition(2), 435,currentPosition(4)];
            end
        end

        % Button pushed function: CallSignalAcquisition
        function CallSignalAcquisitionButtonPushed(app, event)
            % Disable Plot Options button while dialog is open
            app.CallSignalAcquisition.Enable = "off";

            % Call dialog box with input values
            app.DialogApp = SignalAcquisition(app);
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            timetable = app.timeTable.list;

            T = cell2table(timetable,"VariableNames",["Operation Type","Filename","Duration","Frames acquired"]);
            [filename, pth]= uiputfile('*.csv','Save as a csv file','timetable.csv');
            t_file = [pth, filename];

            % 分解filename
            [~,name,~] = fileparts(filename);
            mat_file = [pth, name, '.mat'];

            writetable(T,t_file,'Encoding','GB2312');
            save(mat_file,'timetable');

            % 记录路径是否保存
            app.timetableExported = 1;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if ~app.timetableExported && app.timeTable.grabTimes
                title = "Confirm Save";
                msg = "操作时间序列还未导出为表格,是否保存?";
                selection = uiconfirm(app.UIFigure,msg,title, ...
                    "Options",{'Save','Cancel'}, ...
                    "DefaultOption",1,"CancelOption",2);
                switch selection
                    case 'Save'
                        ExportButtonPushed(app);
                end
            end
            
            if ~isempty(app.cameraObj)
                % disconnect video
                delete(app.cameraObj);
            end

            % 删除回调函数
            delete(app.acqDoneListener);
            delete(app.acqAbortListener);
            app.hSI = scanimage.SI.empty;

            % Delete both apps
            delete(app.DialogApp);
            delete(app);
        end

        % Value changed function: VideoFileIndexSpinner
        function VideoFileIndexSpinnerValueChanged(app, event)
            value = app.VideoFileIndexSpinner.Value;
            if app.ScanimageButton.Value
                app.hSI.hScan2D.logFileCounter = value;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.FilePathEditFieldLabel = uilabel(app.UIFigure);
            app.FilePathEditFieldLabel.Text = 'File Path';
            app.FilePathEditField = uieditfield(app.UIFigure, 'text');
            app.FilePathEditField.Editable = 'off';
            app.FilePathEditField.Value = 'D:\';
            app.FolderSelectButton = uibutton(app.UIFigure, 'push');
            app.FolderSelectButton.ButtonPushedFcn = createCallbackFcn(app, @FolderSelectButtonPushed, true);
            app.FolderSelectButton.Text = '...';
            app.CameraConnectButton = uibutton(app.UIFigure, 'push');
            app.CameraConnectButton.ButtonPushedFcn = createCallbackFcn(app, @CameraConnectButtonPushed, true);
            app.CameraConnectButton.Text = 'Camera Connect';
            app.CameraPreviewButton = uibutton(app.UIFigure, 'push');
            app.CameraPreviewButton.ButtonPushedFcn = createCallbackFcn(app, @CameraPreviewButtonPushed, true);
            app.CameraPreviewButton.Enable = 'off';
            app.CameraPreviewButton.Text = 'Camera Preview';
            app.Lamp = uilamp(app.UIFigure);
            app.WaitingforLabel = uilabel(app.UIFigure);
            app.WaitingforLabel.Text = 'Waiting for ...';
            app.VideoFileIndexSpinnerLabel = uilabel(app.UIFigure);
            app.VideoFileIndexSpinnerLabel.Text = 'Video File Index ';
            app.VideoFileIndexSpinner = uispinner(app.UIFigure);
            app.VideoFileIndexSpinner.ValueChangedFcn = createCallbackFcn(app, @VideoFileIndexSpinnerValueChanged, true);
            app.VideoFileIndexSpinner.Value = 1;
            app.ROIRegionPanel = uipanel(app.UIFigure);
            app.ROIRegionPanel.Title = 'ROI Region';
            app.WidthSpinnerLabel = uilabel(app.ROIRegionPanel);
            app.WidthSpinnerLabel.Text = 'Width';
            app.WidthSpinner = uispinner(app.ROIRegionPanel);
            app.WidthSpinner.Step = 5;
            app.WidthSpinner.ValueDisplayFormat = '%.0f';
            app.WidthSpinner.ValueChangedFcn = createCallbackFcn(app, @WidthSpinnerValueChanged, true);
            app.WidthSpinner.Value = 720;
            app.YOffsetSpinnerLabel = uilabel(app.ROIRegionPanel);
            app.YOffsetSpinnerLabel.Text = 'Y Offset';
            app.YOffsetSpinner = uispinner(app.ROIRegionPanel);
            app.YOffsetSpinner.Step = 5;
            app.YOffsetSpinner.ValueDisplayFormat = '%.0f';
            app.YOffsetSpinner.ValueChangedFcn = createCallbackFcn(app, @YOffsetSpinnerValueChanged, true);
            app.HeightSpinnerLabel = uilabel(app.ROIRegionPanel);
            app.HeightSpinnerLabel.Text = 'Height';
            app.HeightSpinner = uispinner(app.ROIRegionPanel);
            app.HeightSpinner.Step = 5;
            app.HeightSpinner.ValueDisplayFormat = '%.0f';
            app.HeightSpinner.ValueChangedFcn = createCallbackFcn(app, @HeightSpinnerValueChanged, true);
            app.HeightSpinner.Value = 720;
            app.XOffsetSpinnerLabel = uilabel(app.ROIRegionPanel);
            app.XOffsetSpinnerLabel.Text = 'X Offset';
            app.XOffsetSpinner = uispinner(app.ROIRegionPanel);
            app.XOffsetSpinner.Step = 5;
            app.XOffsetSpinner.ValueDisplayFormat = '%.0f';
            app.XOffsetSpinner.ValueChangedFcn = createCallbackFcn(app, @XOffsetSpinnerValueChanged, true);
            app.AdvancedButton = uibutton(app.UIFigure, 'state');
            app.AdvancedButton.ValueChangedFcn = createCallbackFcn(app, @AdvancedButtonValueChanged, true);
            app.AdvancedButton.Text = 'Advanced  >>';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Enable = 'off';
            app.StopButton.Text = 'Stop';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Enable = 'off';
            app.StartButton.Text = 'Start';
            app.CallSignalAcquisition = uibutton(app.UIFigure, 'push');
            app.CallSignalAcquisition.ButtonPushedFcn = createCallbackFcn(app, @CallSignalAcquisitionButtonPushed, true);
            app.CallSignalAcquisition.Text = 'Signal Acquisition';
            app.TimeList = uilistbox(app.UIFigure);
            app.TimeList.Items = {};
            app.TimeList.Value = {};
            app.ExportButton = uibutton(app.UIFigure, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            app.OperationTimeListLabel = uilabel(app.UIFigure);
            app.OperationTimeListLabel.Text = 'Operation Time List';
            app.ScanimageButton = uibutton(app.UIFigure, 'state');
            app.ScanimageButton.ValueChangedFcn = createCallbackFcn(app, @ScanimageButtonValueChanged, true);
            app.ScanimageButton.Text = 'Link Scanimage';
            app.DefaultScaleLabel = uilabel(app.UIFigure);
            app.DefaultScaleLabel.Text = 'Default Scale: 1280x1024';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CameraSync

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end